classdef TrackingTaskGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        StartingModeDropDown         matlab.ui.control.DropDown
        StartingModeDropDownLabel    matlab.ui.control.Label
        ConditionDropDown            matlab.ui.control.DropDown
        ConditionDropDownLabel       matlab.ui.control.Label
        TrackingTaskLabel            matlab.ui.control.Label
        BeginButton                  matlab.ui.control.Button
        blockNum                     matlab.ui.control.EditField
        BlockEditFieldLabel          matlab.ui.control.Label
        subjectNum                   matlab.ui.control.EditField
        SubjectEditFieldLabel        matlab.ui.control.Label
        ParticipantInformationLabel  matlab.ui.control.Label
    end

    
    methods (Access = private)


        function RunExperiment(app)


            [win,rect,ifi] = initializePTB(app);

            screenID = max(Screen('Screens'));

            joy = vrjoystick(2);
            throttle = vrjoystick(1);

            % Load target image ONCE
            [targetImg,~,targetAlpha] = imread('food_0001_sphere_300.png');

            if ~isempty(targetAlpha)
                targetImg(:,:,4) = targetAlpha;
            end

            targetTexture = Screen('MakeTexture',win,targetImg);
            
            [imgHeight,imgWidth,~] = size(targetImg);

            exp.imgHeight = imgHeight;
            exp.imgWidth = imgWidth;
            % Generate background dots ONCE
            numBgDots = 10000;
            worldMin = -20000;
            worldMax = 20000;

            bgDotsX = worldMin + (worldMax-worldMin)*rand(numBgDots,1);
            bgDotsY = worldMin + (worldMax-worldMin)*rand(numBgDots,1);
            bgDotsZ = worldMin + (worldMax-worldMin)*rand(numBgDots,1);

            % Store everything
            exp.win = win;
            exp.rect = rect;
            exp.ifi = ifi;

            exp.joy = joy;
            exp.throttle = throttle;

            exp.targetTexture = targetTexture;

            exp.bgDotsX = bgDotsX;
            exp.bgDotsY = bgDotsY;
            exp.bgDotsZ = bgDotsZ;

            showInstructions(app,win);

            KbReleaseWait;

            while true

                [keyIsDown,~,keyCode] = KbCheck;

                if keyIsDown && keyCode(KbName('SPACE'))
                    break;
                end

            end

            KbReleaseWait;


            if strcmp(app.StartingModeDropDown.Value,'Lateral')
                modeOrder = {'Lateral','Depth'};
            else
                modeOrder = {'Depth','Lateral'};
            end

            paths = {'1','2','3','4','5','6'};

            totalTrials = numel(paths)*2;
            trialNum = 1;
            for p = 1:numel(paths)
                for m = 1:2
                    currentPath = paths{p};
                    currentMode = modeOrder{m};

                    TrackingTask(app,currentMode,currentPath,exp);

                
                    if trialNum < totalTrials
                        showBreakScreen(app,win,15,trialNum,totalTrials);
                    end

                    trialNum = trialNum + 1;
                end
            end
            showCompletionScreen(app,win);

            Screen('Close',exp.targetTexture);

            ShowCursor;

sca;

        end
        
        function showCompletionScreen(app,win)

            msg = ['Slow down there Tiger! \n\n' ...
                'Trial Completed!\n\n'...
                'Press SPACE to exit'];

            DrawFormattedText(win,msg,'center','center',[0 255 0]);
            Screen('Flip',win);
            keyToExit(app,'SPACE');
        end


        function showBreakScreen(app,win,breakTime,taskNum,totalTasks)
            for t = breakTime:-1:1
                checkAbort(app);
                msg = sprintf( ...
                    ['Task %d of %d Complete\n\n' ...
                    'Take a short break\n\n' ...
                    'Next task begins in %d seconds'], ...
                    taskNum,totalTasks,t);

                DrawFormattedText(win,msg,'center','center',[255 255 255]);
                Screen('Flip',win);
                WaitSecs(1);
            end

            msg = 'Press SPACE to start the next task';
            DrawFormattedText(win,msg,'center','center',[0 255 0]);
            Screen('Flip',win);
            keyToExit(app,'SPACE');

        end


        function TrackingTask(app,taskMode,currentPath,exp)
            win = exp.win;
            rect = exp.rect;
            ifi = exp.ifi;
            
            screenID = max(Screen('Screens'));

            screenSizeMM = Screen('DisplaySize',screenID);

            pixelsPerMM = rect(3)/screenSizeMM(1);
            %Frame Rate Properties
            % frameRate = str2double(app.ConditionDropDown.Value);
            frameRate = frameRateSelector(app,app.ConditionDropDown.Value);
            
            frameDuration = 1/frameRate;

            %Camera Properties
            cameraX = 0;
            cameraY = 0;
            cameraZ = 0;
            maxSpeed = 5000;


            % Background Dots Properties
            b_dotSize = 5;
            b_dotColor = [0.35, 0.81, 0.96];
            bgDotsX = exp.bgDotsX;
            bgDotsY = exp.bgDotsY;
            bgDotsZ = exp.bgDotsZ;

            % Cross-hair Properties
            % crsHr_height = 100;
            crosshairSizeMM = 50;
            crsHr_height = round(crosshairSizeMM * pixelsPerMM);
            crsHr_color = [255,255,255];
            crsHr_thickness = 4;

            %Target Properties
            targetSize = 50;
            % Read target image and draw it as a texture on screen
            targetTexture = exp.targetTexture;

            imgHeight = exp.imgHeight;
            imgWidth = exp.imgWidth;

            imgAspect = imgWidth/imgHeight;
            %read selected random motion files from folder
            appFolder = fileparts(mfilename('fullpath'));

            motionFolder = fullfile(appFolder,'motionPaths');

            rmp = readtable(fullfile(motionFolder,sprintf('rmp_%s',currentPath)));
            
            [xTarget,yTarget] = randomMotion(app,rmp);
            zTarget = 500;
            refDistance = 800;
            targetBaseSize = targetSize;
            minTargetSize = 4;
            maxTargetSize = 150;
            % 
            % %Task Mode
            % taskMode = app.StartingModeDropDown.Value;

            %Real-Time average FPS calculation variables
            motionFPSBuffer = [];
            avgMotionFPS = 0;

            %Frame Skipping Variables
            lastMotionTime = GetSecs; %Time stamp of last frame flip
            motionCounter = 1;
            
            %Controller description
            joy = exp.joy;
            throttle = exp.throttle;
            [xCenter, yCenter] = RectCenter(rect); %define the center coordinates of the screen based on screen dimensions
            %Hide the cursor and set the control to the center of the screen
            HideCursor;
            SetMouse(xCenter, yCenter, win);
            % Screen('Flip', win);

            %Task timing
            taskDuration = 60;
            taskStartTime = GetSecs;
            
            %Initialize data struct
            data = struct([]);

            %Font properties
            Screen('TextSize',win,20);
            Screen('TextFont',win,'Arial');

            %
            pathSampleRate = (length(xTarget)-1)/taskDuration; %480Hz
            pathSampleStep = round(pathSampleRate / frameRate);
            dt=0;


            vbl = Screen('Flip', win);
            %MAIN LOOP
            while true
                timeRemaining = max(0, taskDuration - (GetSecs - taskStartTime));
                elapsedTime = taskDuration - timeRemaining;
                timerText = sprintf('Time Remaining = %.1f s', timeRemaining);
                DrawFormattedText(win,timerText,rect(3)-1200,30,[255,255,255]);
                %End the trial once task duration is met
                if GetSecs - taskStartTime >= taskDuration
                    break;
                end

    
                %Display Expected frame rate
                expectedFrameRate = sprintf('Expected Frame Rate = %d FPS \n',frameRate);
                % DrawFormattedText(win,expectedFrameRate,rect(3)-700,30,[255,255,255]);

                %Display Actual Frame Rate
                RealFPS = sprintf('Real Frame Rate = %.2f FPS \n',avgMotionFPS);
                % DrawFormattedText(win,RealFPS,rect(3)-300,30,[255,255,255]);

                %Continously check for 'esc' press to exit program
                checkAbort(app);
                
                [avgMotionFPS,motionFPSBuffer,lastMotionTime] = updateFPS(app,motionFPSBuffer,lastMotionTime);


                % motionCounter = motionCounter + pathStep;
                % motionCounter = min(motionCounter, length(xTarget));
                motionCounter = floor(elapsedTime * pathSampleRate*0.5) + 1;                
                motionCounter = min(motionCounter,length(xTarget));

                % [jx,jy,throttleNorm] = readControls(app,joy,throttle);
                % 
                % [cameraX,cameraY,cameraZ] = updateCamera(app,cameraX,cameraY,jx,jy,throttleNorm,sensitivity,dt);
                [direction,currentSpeed] = readControls(app,joy,throttle,maxSpeed,taskMode);

                [cameraX,cameraY,cameraZ] = updateCamera(app,cameraX,cameraY,cameraZ,direction,currentSpeed,dt);

                switch taskMode
                    case 'Lateral'
                        trueTargetZ = 500;
                    case 'Depth'
                        trueTargetZ = 500 + yTarget(motionCounter);
                end
                distance = trueTargetZ - cameraZ;
                distance = max(distance,1);
                apparentSize = targetBaseSize * (refDistance/distance);
                apparentSize = min(max(apparentSize, minTargetSize), maxTargetSize);

                [visibleDots,visibleSizes] = projectBackgroundDots(app,bgDotsX,bgDotsY,bgDotsZ,cameraX,cameraY,cameraZ,xCenter,yCenter,rect,refDistance,b_dotSize);
                [screen_targetX,screen_targetY,zTarget,targetWorldX,targetWorldY,targetWorldZ] = updateTargetPosition(app,taskMode,xTarget,yTarget,zTarget,motionCounter,cameraX,cameraY,cameraZ,xCenter,yCenter);


                destWidth = apparentSize * 2;
                targetDiameterMM = destWidth / pixelsPerMM;
                destHeight = destWidth/imgAspect;
                destRect = CenterRectOnPointd([0 0 destWidth destHeight], screen_targetX, screen_targetY);


                Screen('DrawDots', win, visibleDots, visibleSizes, b_dotColor, [], 2); % Draw background dots


                Screen('DrawTexture', win, targetTexture, [], destRect);
                app.drawTargetArrow(win,screen_targetX, screen_targetY,xCenter,yCenter,rect);

                %Draw crosshair
                onTarget = app.checkTarget(screen_targetX,screen_targetY,xCenter,yCenter,crsHr_height,crsHr_height,targetDiameterMM);
                DrawCrossHair(app,crsHr_height,crsHr_height,crsHr_color, crsHr_thickness,xCenter,yCenter,onTarget,win);
                data = logData(app,data,currentPath,taskMode,frameRate,screen_targetX,screen_targetY,targetWorldX,targetWorldY,targetWorldZ,distance,cameraX,cameraY,cameraZ,elapsedTime,xCenter,yCenter,crsHr_height,targetDiameterMM);

                
                previousVbl = vbl;

                vbl = Screen('Flip',win,previousVbl+frameDuration-0.5*ifi);


                dt = vbl - previousVbl;

            end

            T = struct2table(data);
            fileName = app.nameOutputFile(app.subjectNum.Value,app.blockNum.Value,app.ConditionDropDown.Value,currentPath,taskMode);
            writetable(T, fileName);

        end

        function fileName = nameOutputFile(~,subject,block,frameRate,path,mode)
            fileName = subject + "_" + block + "_" + frameRate + "_" + path +"_" + mode+".csv";
        end

        function drawTargetArrow(~,win,targetX,targetY,xCenter,yCenter,rect)
            %Only draw if the target is off the screen
            if targetX >= 0 && targetX <= rect(3) && targetY >= 0 && targetY <= rect(4)
                return;
            end

            %Create a margin as the screen edge
            margin = 60;

            arrowX = min(max(targetX,margin),rect(3)-margin);
            arrowY = min(max(targetY,margin),rect(4)-margin);

            ang = atan2(targetY-yCenter,targetX-xCenter);

            arrowLength = 50;
            arrowWidth = 25;

            tip = [arrowX arrowY];

            % Base center
            baseCenter = tip - arrowLength * [cos(ang) sin(ang)];

            % Perpendicular vector
            perp = [-sin(ang) cos(ang)];

            % Base corners
            left  = baseCenter + arrowWidth*perp;
            right = baseCenter - arrowWidth*perp;

            % Polygon vertices
            pts = [tip;
                left;
                right];

            % Draw yellow arrow with black border
            Screen('FillPoly',win,[255 255 0],pts);
            Screen('FramePoly',win,[0 0 0],pts,3);

        end

        function data = logData(app,data,currentPath,taskMode,frameRate,screen_targetX,screen_targetY,targetWorldX,targetWorldY,targetWorldZ,distance,cameraX,cameraY,cameraZ,elapsedTime,xCenter,yCenter,crsHr_height,targetDiameterMM)
            idx = numel(data) + 1;

            data(idx).SubjectNum = app.subjectNum.Value;
            data(idx).blockNum   = app.blockNum.Value;
            data(idx).frameRate  = frameRate;
            data(idx).randomMotionPath = currentPath;
            data(idx).taskMode = taskMode;

            data(idx).TimeStamp = elapsedTime;
            data(idx).Txi = targetWorldX;
            data(idx).Tyi = targetWorldY;
            data(idx).Tzi = targetWorldZ;

            data(idx).Cxi = cameraX;
            data(idx).Cyi = cameraY;
            data(idx).Czi = cameraZ;

            % Tracking error (world coordinates)
            data(idx).ErrorX = targetWorldX - cameraX;
            data(idx).ErrorY = targetWorldY - cameraY;
            data(idx).ErrorZ = targetWorldZ - cameraZ;

            data(idx).onTarget = app.checkTarget(screen_targetX,screen_targetY,xCenter,yCenter,crsHr_height,crsHr_height,targetDiameterMM);
        end

        function showInstructions(~,win)
            %Start Screen
            startText = ['Welcome to the Tracking Task \n\n' ...
                'Move the mouse to control the cross-hair\n'...
                'Track the dot and attempt to keep it in the white rectangular borders of the crosshair \n\n' ...
                'Press Space to begin']; %Instructional text
            Screen('TextSize',win,40);
            Screen('TextFont',win,'Arial');
            DrawFormattedText(win,startText,'center','center',[0,1,0]); %Draws text on screen in green
            Screen('Flip',win);
        end

        function [win,rect,ifi] = initializePTB(~)
            %PTB Setup
            Screen('Preference', 'ConserveVRAM', 4096);
            Screen('Preference', 'SkipSyncTests', 1); %Setting to skip synching test, 0-to enable, 1-to skip
            Screen('Preference', 'VisualDebugLevel', 0);
            KbName('UnifyKeyNames');
            PsychDefaultSetup(2);
            screenID = max(Screen('Screens')); %Retrieve screen ID of the secondary display.
            % PsychDebugWindowConfiguration(0,0.5);
            [win, rect] = PsychImaging('OpenWindow', screenID, [0 0 0]);
            Screen('BlendFunction', win, GL_SRC_ALPHA, GL_ONE_MINUS_SRC_ALPHA);%Anti-aliasing and transparency
            ifi = Screen('GetFlipInterval', win); %Inter Frame Interval. Measures exact duration of a single frame on monitor in seconds.

        end

        
        function [avgMotionFPS,motionFPSBuffer,lastMotionTime] = updateFPS(~,motionFPSBuffer,lastMotionTime)
            currentMotionTime = GetSecs;

            motionInterval = currentMotionTime - lastMotionTime;
            lastMotionTime = currentMotionTime;

            if motionInterval > 0
                instantMotionFPS = 1/motionInterval;
                motionFPSBuffer(end+1) = instantMotionFPS;

                if numel(motionFPSBuffer) > 20
                    motionFPSBuffer(1) = [];
                end
                avgMotionFPS = mean(motionFPSBuffer);
            end
        end

        function [direction,currentSpeed] = readControls(~,joy,throttle,maxSpeed,taskMode)
            % Read all axes and buttons
            axesJoy = read(joy);
            [axesThrottle] = read(throttle);
            jx = axesJoy(1);   % X axis (left/right)

            switch taskMode
                case 'Lateral'
                    jy = -axesJoy(2);   % Y axis (forward/back)
                    jz = 0;
                case 'Depth'
                    % Typical Warthog mapping:
                    jy = 0;
                    jz = -axesJoy(2);   % Y axis (forward/back)
                    
            end
            direction = [jx,jy,jz];
            speedCommand = (1 - axesThrottle(3))/2;
            currentSpeed = speedCommand*maxSpeed;
            mag = norm(direction);

            if mag > 0.03
                direction = direction ./mag;
            else
                direction = [0 0 0];
            end

        end

        function [cameraX,cameraY,cameraZ] = updateCamera(~,cameraX,cameraY,cameraZ,direction,currentSpeed,dt)
            % Apply movement
            cameraX = cameraX + direction(1) * currentSpeed*dt;
            cameraY = cameraY + direction(2) * currentSpeed*dt;
            cameraZ = cameraZ + direction(3) * currentSpeed*dt;
        end

        function [visibleDots,visibleSizes] = projectBackgroundDots(~,bgDotsX,bgDotsY,bgDotsZ,cameraX,cameraY,cameraZ,xCenter,yCenter,rect,refDistance,b_dotSize)
            %Convert background dots to screen coordinates
            relX = bgDotsX - cameraX;
            relY = bgDotsY   - cameraY;
            relZ = bgDotsZ - cameraZ;
            relZ = max(relZ,1);

            scale = refDistance ./ relZ;

            screen_b_DotsX = xCenter + relX .* scale;
            screen_b_DotsY = yCenter + relY .* scale;

            dotSizes = b_dotSize .* scale;
            dotSizes = max(dotSizes, 1);          % prevent too-small dots
            dotSizes = min(dotSizes, 150);        % optional: prevent giant dots


            %Only draw background dots that are on-screen to optimize
            %performance
            onScreen = screen_b_DotsX > 0 & screen_b_DotsX < rect(3) & screen_b_DotsY > 0 & screen_b_DotsY < rect(4); %background dots must stay within the x and y boundaries of the screen
            visibleDots = [screen_b_DotsX(onScreen)'; screen_b_DotsY(onScreen)'];
            visibleSizes = dotSizes(onScreen);
        end

        function [screen_targetX,screen_targetY,zTarget,targetWorldX,targetWorldY,targetWorldZ] = updateTargetPosition(~,taskMode,xTarget,yTarget,zTarget,motionCounter,cameraX,cameraY,cameraZ,xCenter,yCenter)
            %Draw target centered on the screen
            switch taskMode
                case 'Lateral'
                    % World coordinates
                    targetWorldX = xTarget(motionCounter);
                    targetWorldY = yTarget(motionCounter);
                    targetWorldZ = 200;
                    % Screen coordinates
                    screen_targetX = xCenter + xTarget(motionCounter) - cameraX;
                    screen_targetY = yCenter + yTarget(motionCounter) - cameraY;
                    zTarget = zTarget - cameraZ;

                case 'Depth'
                    % World coordinates
                    targetWorldX = xTarget(motionCounter);
                    targetWorldY = 0;
                    targetWorldZ = 500 + yTarget(motionCounter);
                    %Screen Coordinates
                    screen_targetX = xCenter + xTarget(motionCounter) - cameraX;
                    screen_targetY = yCenter - cameraY;
                    zTarget = 500 + yTarget(motionCounter)-cameraZ;
            end
        end
        

        function DrawCrossHair(~,width, height, color, thickness, xCenter, yCenter, onTarget, win)
            halfW = width/2;
            halfH = height/2;

            if onTarget
                color = [255,0,0];
            end

            % How far the corner brackets extend along each edge (tune this ratio)
            bracketLenX = halfW * 0.6;
            bracketLenY = halfH * 0.6;

            % How far the crosshair lines extend beyond the square
            lineExtentX = halfW * 1.4;
            lineExtentY = halfH * 1.4;

            % --- Full horizontal line (passes through, extends past square) ---
            Screen('DrawLine', win, color, ...
                xCenter - lineExtentX, yCenter, ...
                xCenter + lineExtentX, yCenter, thickness);

            % --- Full vertical line (passes through, extends past square) ---
            Screen('DrawLine', win, color, ...
                xCenter, yCenter - lineExtentY, ...
                xCenter, yCenter + lineExtentY, thickness);

            % --- Top-left bracket ---
            Screen('DrawLine', win, color, xCenter - halfW, yCenter - halfH, xCenter - halfW + bracketLenX, yCenter - halfH, thickness); % top edge
            Screen('DrawLine', win, color, xCenter - halfW, yCenter - halfH, xCenter - halfW, yCenter - halfH + bracketLenY, thickness); % left edge

            % --- Top-right bracket ---
            Screen('DrawLine', win, color, xCenter + halfW, yCenter - halfH, xCenter + halfW - bracketLenX, yCenter - halfH, thickness);
            Screen('DrawLine', win, color, xCenter + halfW, yCenter - halfH, xCenter + halfW, yCenter - halfH + bracketLenY, thickness);

            % --- Bottom-left bracket ---
            Screen('DrawLine', win, color, xCenter - halfW, yCenter + halfH, xCenter - halfW + bracketLenX, yCenter + halfH, thickness);
            Screen('DrawLine', win, color, xCenter - halfW, yCenter + halfH, xCenter - halfW, yCenter + halfH - bracketLenY, thickness);

            % --- Bottom-right bracket ---
            Screen('DrawLine', win, color, xCenter + halfW, yCenter + halfH, xCenter + halfW - bracketLenX, yCenter + halfH, thickness);
            Screen('DrawLine', win, color, xCenter + halfW, yCenter + halfH, xCenter + halfW, yCenter + halfH - bracketLenY, thickness);
        end

        function [x,y] = randomMotion(~,motionFile)
            % idx = 1:100:length(motionFile.X);

            % xCoarse = motionFile.X(idx);
            % yCoarse = motionFile.Y(idx);

            % stretchFactor = 4;
            % scaleFactor = 700;
            % 
            % t = 1:length(xCoarse);
            % ti = linspace(1,length(xCoarse),length(motionFile.X)*stretchFactor);
            % 
            % x = interp1(t,xCoarse,ti,'pchip') * scaleFactor;
            % y = interp1(t,yCoarse,ti,'pchip') * scaleFactor;
            startRadius = 250; % pixels
            x = motionFile.X*1200;
            y = motionFile.Y*1200;

            x = x - x(1);
            y = y - y(1);

            x = x + startRadius;

        end

        function isOnTarget = checkTarget(~,targetX,targetY,xCenter,yCenter,crsHairHeight,crsHairWidth,targetDiameterMM)
            withinX = targetX < xCenter + crsHairWidth/2 && targetX > xCenter - crsHairWidth/2;
            withinY = targetY < yCenter + crsHairHeight/2 && targetY > yCenter - crsHairHeight/2;
            sizeOK = targetDiameterMM >= 40 && targetDiameterMM <= 60;
            isOnTarget = withinX && withinY &&sizeOK;
            %     isOnTarget = true;
            % else
            %     isOnTarget = false;
            % end
        end

        function frameRate = frameRateSelector(~,letterCode)
            switch letterCode
                case 'A'
                    frameRate = 60;
                case 'B'
                    frameRate = 120;
                case 'C' 
                    frameRate = 240;
                case 'D'
                    frameRate = 480;
            end
        end
        function checkAbort(~)

            [keyIsDown,~,keyCode] = KbCheck;

            if keyIsDown && keyCode(KbName('ESCAPE'))

                ShowCursor;
                Screen('CloseAll');

                error('Experiment aborted by user');

            end

        end
        function keyToExit(app,keyName)

            KbReleaseWait;

            while true

                checkAbort(app);

                [keyIsDown,~,keyCode] = KbCheck;

                if keyIsDown && keyCode(KbName(keyName))
                    break;
                end

                WaitSecs(0.01);

            end

            KbReleaseWait;

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BeginButton
        function BeginButtonPushed(app, event)
            RunExperiment(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ParticipantInformationLabel = uilabel(app.UIFigure);
            app.ParticipantInformationLabel.Text = 'Participant Information';
            app.SubjectEditFieldLabel = uilabel(app.UIFigure);
            app.SubjectEditFieldLabel.Text = 'Subject #';
            app.subjectNum = uieditfield(app.UIFigure, 'text');
            app.BlockEditFieldLabel = uilabel(app.UIFigure);
            app.BlockEditFieldLabel.Text = 'Block #';
            app.blockNum = uieditfield(app.UIFigure, 'text');
            app.BeginButton = uibutton(app.UIFigure, 'push');
            app.BeginButton.ButtonPushedFcn = createCallbackFcn(app, @BeginButtonPushed, true);
            app.BeginButton.Text = 'Begin';
            app.TrackingTaskLabel = uilabel(app.UIFigure);
            app.TrackingTaskLabel.Text = 'Tracking Task ';
            app.ConditionDropDownLabel = uilabel(app.UIFigure);
            app.ConditionDropDownLabel.Text = 'Condition';
            app.ConditionDropDown = uidropdown(app.UIFigure);
            app.ConditionDropDown.Items = {'A', 'B', 'C', 'D'};
            app.ConditionDropDown.Value = 'A';
            app.StartingModeDropDownLabel = uilabel(app.UIFigure);
            app.StartingModeDropDownLabel.Text = 'Starting Mode';
            app.StartingModeDropDown = uidropdown(app.UIFigure);
            app.StartingModeDropDown.Items = {'Lateral', 'Depth'};
            app.StartingModeDropDown.Value = 'Lateral';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrackingTaskGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end